classdef estatura < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        estaturaEditFieldLabel  matlab.ui.control.Label
        estaturaEditField       matlab.ui.control.NumericEditField
        aceptarButton           matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: estaturaEditField
        function estaturaEditFieldValueChanged(app, event)
            value = app.estaturaEditField.Value;
            
        end

        % Button pushed function: aceptarButton
        function aceptarButtonPushed(app, event)
            estatura=app.estaturaEditField.Value
            categoria=""
            
            if estatura<1.65
               categoria="baja" 
            end
            if estatura>=1.65 & estatura<=1.70
                categoria="media"
            end
            if estatura>1.70
               categoria="alta" 
            end
            mensaje= sprintf("la persona es de estatura %s",categoria)
            
            msgbox (mensaje,"modal")
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.estaturaEditFieldLabel = uilabel(app.UIFigure);
            app.estaturaEditFieldLabel.Text = 'estatura';
            app.estaturaEditField = uieditfield(app.UIFigure, 'numeric');
            app.estaturaEditField.ValueChangedFcn = createCallbackFcn(app, @estaturaEditFieldValueChanged, true);
            app.aceptarButton = uibutton(app.UIFigure, 'push');
            app.aceptarButton.ButtonPushedFcn = createCallbackFcn(app, @aceptarButtonPushed, true);
            app.aceptarButton.Text = 'aceptar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = estatura

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end