classdef figuras < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        tipodefiguraButtonGroup  matlab.ui.container.ButtonGroup
        trianguloButton          matlab.ui.control.RadioButton
        rectanguloButton         matlab.ui.control.RadioButton
        circuloButton            matlab.ui.control.RadioButton
        calcularareaButton       matlab.ui.control.Button
        trianguloPanel           matlab.ui.container.Panel
        baseEditFieldLabel       matlab.ui.control.Label
        baseEditField            matlab.ui.control.NumericEditField
        alturaEditFieldLabel     matlab.ui.control.Label
        alturaEditField          matlab.ui.control.NumericEditField
        areaLabel                matlab.ui.control.Label
        resultado                matlab.ui.control.Label
        rectanguloPanel          matlab.ui.container.Panel
        baseEditField_2Label     matlab.ui.control.Label
        baseEditField_2          matlab.ui.control.NumericEditField
        alturaEditField_2Label   matlab.ui.control.Label
        alturaEditField_2        matlab.ui.control.NumericEditField
        areaLabel_2              matlab.ui.control.Label
        resultado_2              matlab.ui.control.Label
        circuloPanel             matlab.ui.container.Panel
        radioEditFieldLabel      matlab.ui.control.Label
        radioEditField           matlab.ui.control.NumericEditField
        areaLabel_3              matlab.ui.control.Label
        resultado_3              matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: calcularareaButton
        function calcularareaButtonPushed(app, event)
            opc=app.tipodefiguraButtonGroup.SelectedObject.Text
            
            switch (opc)
                case 'triangulo'
                   altura=app.alturaEditField.Value
                   base=app.baseEditField.Value
                   
                   area=altura*base/2
                   
                   app.resultado.Text=num2str(area)
                case 'rectangulo'
                   altura=app.alturaEditField_2.Value
                   base=app.baseEditField_2.Value
                   
                   area=altura*base
                   
                   app.resultado_2.Text=num2str(area) 
                case 'circulo'
                    radio= app.radioEditField.Value
                    
                    area=pi*radio^2
                    
                    app.resultado_3.Text=num2str(area)
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.tipodefiguraButtonGroup = uibuttongroup(app.UIFigure);
            app.tipodefiguraButtonGroup.Title = 'tipo de figura';
            app.trianguloButton = uiradiobutton(app.tipodefiguraButtonGroup);
            app.trianguloButton.Text = 'triangulo';
            app.trianguloButton.Value = true;
            app.rectanguloButton = uiradiobutton(app.tipodefiguraButtonGroup);
            app.rectanguloButton.Text = 'rectangulo';
            app.circuloButton = uiradiobutton(app.tipodefiguraButtonGroup);
            app.circuloButton.Text = 'circulo';
            app.calcularareaButton = uibutton(app.UIFigure, 'push');
            app.calcularareaButton.ButtonPushedFcn = createCallbackFcn(app, @calcularareaButtonPushed, true);
            app.calcularareaButton.Text = 'calcular area';
            app.trianguloPanel = uipanel(app.UIFigure);
            app.trianguloPanel.Title = 'triangulo';
            app.baseEditFieldLabel = uilabel(app.trianguloPanel);
            app.baseEditFieldLabel.Text = 'base';
            app.baseEditField = uieditfield(app.trianguloPanel, 'numeric');
            app.alturaEditFieldLabel = uilabel(app.trianguloPanel);
            app.alturaEditFieldLabel.Text = 'altura';
            app.alturaEditField = uieditfield(app.trianguloPanel, 'numeric');
            app.areaLabel = uilabel(app.trianguloPanel);
            app.areaLabel.Text = 'area: ';
            app.resultado = uilabel(app.trianguloPanel);
            app.resultado.Text = '';
            app.rectanguloPanel = uipanel(app.UIFigure);
            app.rectanguloPanel.Title = 'rectangulo';
            app.baseEditField_2Label = uilabel(app.rectanguloPanel);
            app.baseEditField_2Label.Text = 'base';
            app.baseEditField_2 = uieditfield(app.rectanguloPanel, 'numeric');
            app.alturaEditField_2Label = uilabel(app.rectanguloPanel);
            app.alturaEditField_2Label.Text = 'altura';
            app.alturaEditField_2 = uieditfield(app.rectanguloPanel, 'numeric');
            app.areaLabel_2 = uilabel(app.rectanguloPanel);
            app.areaLabel_2.Text = 'area: ';
            app.resultado_2 = uilabel(app.rectanguloPanel);
            app.resultado_2.Text = '';
            app.circuloPanel = uipanel(app.UIFigure);
            app.circuloPanel.Title = 'circulo';
            app.radioEditFieldLabel = uilabel(app.circuloPanel);
            app.radioEditFieldLabel.Text = 'radio';
            app.radioEditField = uieditfield(app.circuloPanel, 'numeric');
            app.areaLabel_3 = uilabel(app.circuloPanel);
            app.areaLabel_3.Text = 'area: ';
            app.resultado_3 = uilabel(app.circuloPanel);
            app.resultado_3.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = figuras

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end