classdef mayormenor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        opcButtonGroup         matlab.ui.container.ButtonGroup
        mayorButton            matlab.ui.control.RadioButton
        medioButton            matlab.ui.control.RadioButton
        menorButton            matlab.ui.control.RadioButton
        numeroaEditFieldLabel  matlab.ui.control.Label
        numeroaEditField       matlab.ui.control.NumericEditField
        numerobEditFieldLabel  matlab.ui.control.Label
        numerobEditField       matlab.ui.control.NumericEditField
        numerocEditFieldLabel  matlab.ui.control.Label
        numerocEditField       matlab.ui.control.NumericEditField
        buscarButton           matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: buscarButton
        function buscarButtonPushed(app, event)
            opc=app.opcButtonGroup.SelectedObject.Text
            
            a=app.numeroaEditField.Value
            b=app.numerobEditField.Value
            c=app.numerocEditField.Value
            
            mensaje=''
            switch (opc)
                case 'mayor'
                    if (a>b)&&(a>c)
                        mensaje=sprintf('el numero mayor es a con valor de: %d',a)
                    end
                    if (b>a)&&(b>c)
                        mensaje=sprintf('el numero mayor es b con valor de: %d',b)
                    end
                    if (c>a)&&(c>b)
                        mensaje=sprintf('el numero mayor es c con valor de: %d',c)
                    end
                    
                case 'medio'
                    if (a<b) && (a>c)||(a<c) && (a>b)
                            mensaje=sprintf('el numero medio es a con valor de: %d',a)
                       
                    end
                    if (b<a) && (b>c)||(b<c) && (b>a)
                            mensaje=sprintf('el numero medio es b con valor de: %d',b)
                        
                    end
                    if (c<b) && (c>a)||(c<a) && (c>b)
                            mensaje=sprintf('el numero medio es c con valor de: %d',c)
                        
                    end
                case 'menor'
                    if (a<b)&&(a<c)
                        mensaje=sprintf('el numero menor es a con valor de: %d',a)
                    end
                    if (b<a)&&(b<c)
                        mensaje=sprintf('el numero menor es b con valor de: %d',b)
                    end
                    if (c<a)&&(c<b)
                        mensaje=sprintf('el numero menor es c con valor de: %d',c)
                    end
                    
            end
            msgbox(mensaje)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.opcButtonGroup = uibuttongroup(app.UIFigure);
            app.opcButtonGroup.Title = 'escoja que numero quiere hallar';
            app.mayorButton = uiradiobutton(app.opcButtonGroup);
            app.mayorButton.Text = 'mayor';
            app.mayorButton.Value = true;
            app.medioButton = uiradiobutton(app.opcButtonGroup);
            app.medioButton.Text = 'medio';
            app.menorButton = uiradiobutton(app.opcButtonGroup);
            app.menorButton.Text = 'menor';
            app.numeroaEditFieldLabel = uilabel(app.UIFigure);
            app.numeroaEditFieldLabel.Text = 'numero a';
            app.numeroaEditField = uieditfield(app.UIFigure, 'numeric');
            app.numerobEditFieldLabel = uilabel(app.UIFigure);
            app.numerobEditFieldLabel.Text = 'numero b';
            app.numerobEditField = uieditfield(app.UIFigure, 'numeric');
            app.numerocEditFieldLabel = uilabel(app.UIFigure);
            app.numerocEditFieldLabel.Text = 'numero c';
            app.numerocEditField = uieditfield(app.UIFigure, 'numeric');
            app.buscarButton = uibutton(app.UIFigure, 'push');
            app.buscarButton.ButtonPushedFcn = createCallbackFcn(app, @buscarButtonPushed, true);
            app.buscarButton.Text = 'buscar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mayormenor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end