classdef promedi < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        numeroaEditFieldLabel  matlab.ui.control.Label
        numeroaEditField       matlab.ui.control.NumericEditField
        numerobEditFieldLabel  matlab.ui.control.Label
        numerobEditField       matlab.ui.control.NumericEditField
        numerocEditFieldLabel  matlab.ui.control.Label
        numerocEditField       matlab.ui.control.NumericEditField
        promedioCheckBox       matlab.ui.control.CheckBox
        desviacionCheckBox     matlab.ui.control.CheckBox
        calcularButton         matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: calcularButton
        function calcularButtonPushed(app, event)
            a=app.numeroaEditField.Value
            b=app.numerobEditField.Value
            c=app.numerocEditField.Value
            mensaje=''
            
            if app.promedioCheckBox.Value
                promedio=mean([a,b,c])
               mensaje=strcat(mensaje,sprintf("el promedio es %f \n",promedio))
            end
            if app.desviacionCheckBox.Value
                desviacion=std([a,b,c])
                mensaje=strcat(mensaje,sprintf("la desviacion es %f",desviacion))
            end
                
                
            msgbox(mensaje)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.numeroaEditFieldLabel = uilabel(app.UIFigure);
            app.numeroaEditFieldLabel.Text = 'numero a';
            app.numeroaEditField = uieditfield(app.UIFigure, 'numeric');
            app.numerobEditFieldLabel = uilabel(app.UIFigure);
            app.numerobEditFieldLabel.Text = 'numero b';
            app.numerobEditField = uieditfield(app.UIFigure, 'numeric');
            app.numerocEditFieldLabel = uilabel(app.UIFigure);
            app.numerocEditFieldLabel.Text = 'numero c';
            app.numerocEditField = uieditfield(app.UIFigure, 'numeric');
            app.promedioCheckBox = uicheckbox(app.UIFigure);
            app.promedioCheckBox.Text = 'promedio';
            app.desviacionCheckBox = uicheckbox(app.UIFigure);
            app.desviacionCheckBox.Text = 'desviacion';
            app.calcularButton = uibutton(app.UIFigure, 'push');
            app.calcularButton.ButtonPushedFcn = createCallbackFcn(app, @calcularButtonPushed, true);
            app.calcularButton.Text = 'calcular';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = promedi

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end