classdef salario < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        sueldoEditFieldLabel  matlab.ui.control.Label
        sueldoEditField       matlab.ui.control.NumericEditField
        nombreEditFieldLabel  matlab.ui.control.Label
        nombreEditField       matlab.ui.control.EditField
        Button                matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            
            nombre=app.nombreEditField.Value
            
            salario=app.sueldoEditField.Value
            mensaje=""
            if (salario >3000000)
    
                mensaje= sprintf("la persona %s tiene un buen sueldo",nombre)
    
            elseif (salario <=3000000 & salario>=1200000)
    
                mensaje= sprintf("la persona %s tiene un sueldo normal",nombre)
            elseif(salario<1200000)
                mensaje= sprintf("la persona %s tiene un sueldo bajo",nombre)
            end
            
            msgbox (mensaje,"modal")
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.sueldoEditFieldLabel = uilabel(app.UIFigure);
            app.sueldoEditFieldLabel.Text = 'sueldo';
            app.sueldoEditField = uieditfield(app.UIFigure, 'numeric');
            app.nombreEditFieldLabel = uilabel(app.UIFigure);
            app.nombreEditFieldLabel.Text = 'nombre';
            app.nombreEditField = uieditfield(app.UIFigure, 'text');
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = salario

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end