classdef taller < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        TabGroup                 matlab.ui.container.TabGroup
        PrimosTab                matlab.ui.container.Tab
        numeroSpinnerLabel       matlab.ui.control.Label
        numeroSpinner            matlab.ui.control.Spinner
        esprimoButton            matlab.ui.control.StateButton
        AnguloTab                matlab.ui.container.Tab
        amplitudSliderLabel      matlab.ui.control.Label
        amplitudSlider           matlab.ui.control.Slider
        clasificarButton         matlab.ui.control.StateButton
        FactorialTab             matlab.ui.container.Tab
        numeroSpinner_2Label     matlab.ui.control.Label
        numeroSpinner_2          matlab.ui.control.Spinner
        calcularfactorialButton  matlab.ui.control.StateButton
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: esprimoButton
        function esprimoButtonValueChanged(app, event)
            numero=app.numeroSpinner.Value
            x=1
            divisores=0
            while x<=numero
                if mod(numero,x)==0
                    divisores=1+divisores
                end
                x=x+1
            end
            
            if divisores==2
                msgbox (sprintf("el numero %d es primo",numero))
            else
                msgbox (sprintf("el numero %d no es primo",numero))
            end
            
        end

        % Value changed function: clasificarButton
        function clasificarButtonValueChanged(app, event)
            amplitud=app.amplitudSlider.Value
            
            if amplitud<90
                msgbox("el angulo es agudo")
            end
            if amplitud==90
                msgbox("el angulo es recto")
            end
            if amplitud>90
                msgbox("el angulo es obtuso")
            end
            if amplitud<180 && amplitud>90
                msgbox("el angulo es convexo")
            end
            if amplitud==180
                msgbox("el angulo es llano")
            end
            if amplitud>180
                msgbox("el angulo es concavo")
            end
            if amplitud==0
                msgbox("el angulo es nulo")
            end
            if amplitud==360
                msgbox("el angulo es agudo")
            end
        end

        % Value changed function: calcularfactorialButton
        function calcularfactorialButtonValueChanged(app, event)
            numero=app.numeroSpinner_2.Value
            factorial=1;
            for i=1:numero
                factorial=factorial*i;
            end
            msgbox(sprintf("el factorial del numero %d es: %d",numero,factorial))
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PrimosTab = uitab(app.TabGroup);
            app.PrimosTab.Title = 'Primos';
            app.numeroSpinnerLabel = uilabel(app.PrimosTab);
            app.numeroSpinnerLabel.Text = 'numero';
            app.numeroSpinner = uispinner(app.PrimosTab);
            app.numeroSpinner.Limits = [0 Inf];
            app.esprimoButton = uibutton(app.PrimosTab, 'state');
            app.esprimoButton.ValueChangedFcn = createCallbackFcn(app, @esprimoButtonValueChanged, true);
            app.esprimoButton.Text = 'es primo';
            app.AnguloTab = uitab(app.TabGroup);
            app.AnguloTab.Title = 'Angulo';
            app.amplitudSliderLabel = uilabel(app.AnguloTab);
            app.amplitudSliderLabel.Text = 'amplitud';
            app.amplitudSlider = uislider(app.AnguloTab);
            app.amplitudSlider.Limits = [0 360];
            app.clasificarButton = uibutton(app.AnguloTab, 'state');
            app.clasificarButton.ValueChangedFcn = createCallbackFcn(app, @clasificarButtonValueChanged, true);
            app.clasificarButton.Text = 'clasificar';
            app.FactorialTab = uitab(app.TabGroup);
            app.FactorialTab.Title = 'Factorial';
            app.numeroSpinner_2Label = uilabel(app.FactorialTab);
            app.numeroSpinner_2Label.Text = 'numero';
            app.numeroSpinner_2 = uispinner(app.FactorialTab);
            app.calcularfactorialButton = uibutton(app.FactorialTab, 'state');
            app.calcularfactorialButton.ValueChangedFcn = createCallbackFcn(app, @calcularfactorialButtonValueChanged, true);
            app.calcularfactorialButton.Text = 'calcular factorial';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = taller

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end