classdef temperatura < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        TemperarutaTab                 matlab.ui.container.Tab
        KnobLabel                      matlab.ui.control.Label
        Knob                           matlab.ui.control.Knob
        GaugeLabel                     matlab.ui.control.Label
        Gauge                          matlab.ui.control.Gauge
        temperaturabajaLampLabel       matlab.ui.control.Label
        temperaturabajaLamp            matlab.ui.control.Lamp
        temperaturanormalLampLabel     matlab.ui.control.Label
        temperaturanormalLamp          matlab.ui.control.Lamp
        temperaturapeligrosaLampLabel  matlab.ui.control.Label
        temperaturapeligrosaLamp       matlab.ui.control.Lamp
        AleatorioTab                   matlab.ui.container.Tab
        numeroaleatorioGaugeLabel      matlab.ui.control.Label
        numeroaleatorioGauge           matlab.ui.control.Gauge
        SwitchLabel                    matlab.ui.control.Label
        Switch                         matlab.ui.control.Switch
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Knob
        function KnobValueChanged(app, event)
            value = app.Knob.Value;
            
            
            app.Gauge.Value=value
        end

        % Value changing function: Knob
        function KnobValueChanging(app, event)
            changingValue = event.Value;
            
            if changingValue <20
                app.temperaturabajaLamp.Enable=true
                app.temperaturanormalLamp.Enable=false
                app.temperaturapeligrosaLamp.Enable=false
            end
            if changingValue>=20 && changingValue <40
                app.temperaturabajaLamp.Enable=false
                app.temperaturanormalLamp.Enable=true
                app.temperaturapeligrosaLamp.Enable=false
                
            end
            if changingValue>=40
                
                app.temperaturabajaLamp.Enable=false
                app.temperaturanormalLamp.Enable=false
                app.temperaturapeligrosaLamp.Enable=true
                
            end


            app.Gauge.Value=changingValue
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            
            if app.Switch.Value=='n'
                r = randi([0 100],1)
                app.numeroaleatorioGauge.Value=r
            else
                app.numeroaleatorioGauge.Value=0
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TemperarutaTab = uitab(app.TabGroup);
            app.TemperarutaTab.Title = 'Temperaruta';
            app.KnobLabel = uilabel(app.TemperarutaTab);
            app.KnobLabel.Text = 'Knob';
            app.Knob = uiknob(app.TemperarutaTab, 'continuous');
            app.Knob.Limits = [0 80];
            app.Knob.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.Knob.ValueChangingFcn = createCallbackFcn(app, @KnobValueChanging, true);
            app.GaugeLabel = uilabel(app.TemperarutaTab);
            app.GaugeLabel.Text = 'Gauge';
            app.Gauge = uigauge(app.TemperarutaTab, 'circular');
            app.Gauge.Limits = [0 80];
            app.temperaturabajaLampLabel = uilabel(app.TemperarutaTab);
            app.temperaturabajaLampLabel.Text = 'temperatura baja';
            app.temperaturabajaLamp = uilamp(app.TemperarutaTab);
            app.temperaturanormalLampLabel = uilabel(app.TemperarutaTab);
            app.temperaturanormalLampLabel.Text = 'temperatura normal';
            app.temperaturanormalLamp = uilamp(app.TemperarutaTab);
            app.temperaturapeligrosaLampLabel = uilabel(app.TemperarutaTab);
            app.temperaturapeligrosaLampLabel.Text = 'temperatura peligrosa';
            app.temperaturapeligrosaLamp = uilamp(app.TemperarutaTab);
            app.AleatorioTab = uitab(app.TabGroup);
            app.AleatorioTab.Title = 'Aleatorio';
            app.numeroaleatorioGaugeLabel = uilabel(app.AleatorioTab);
            app.numeroaleatorioGaugeLabel.Text = 'numero aleatorio';
            app.numeroaleatorioGauge = uigauge(app.AleatorioTab, 'circular');
            app.SwitchLabel = uilabel(app.AleatorioTab);
            app.SwitchLabel.Text = 'Switch';
            app.Switch = uiswitch(app.AleatorioTab, 'slider');
            app.Switch.Items = {'0', 'n'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = '0';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = temperatura

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end